classdef DELLO_loc_Panel_auto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DELLOLocAutoUIFigure  matlab.ui.Figure
        UIAxes_1              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        Slider_1              matlab.ui.control.Slider
        Slider_2              matlab.ui.control.Slider
        Slider_3              matlab.ui.control.Slider
        Slider_4              matlab.ui.control.Slider
        TargetButton          matlab.ui.control.Button
        EntryButton           matlab.ui.control.Button
        EditFieldTargetCorr   matlab.ui.control.EditField
        EditField_2Label      matlab.ui.control.Label
        EditFieldEntryCorr    matlab.ui.control.EditField
        DepthElectrodesCoordinatesLabel  matlab.ui.control.Label
        FinishButton          matlab.ui.control.Button
        UITableElecInfo       matlab.ui.control.Table
        GenerateButton        matlab.ui.control.Button
        EditField             matlab.ui.control.EditField
        StatusLabel           matlab.ui.control.Label
    end


    properties (Access = public)
        ProcessedObj % Preprocessed object
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PrepObj)
            app.ProcessedObj = PrepObj;
            app.Slider_1.Limits = [1 size(app.ProcessedObj.AnatMat,1)];
            app.Slider_2.Limits = [1 size(app.ProcessedObj.AnatMat,2)];
            app.Slider_3.Limits = [1 size(app.ProcessedObj.AnatMat,3)];
            app.Slider_4.Limits = [1 size(app.ProcessedObj.Corr,1)];
            camroll(app.UIAxes_1,90)
            camroll(app.UIAxes_2,90)
            camroll(app.UIAxes_3,90)
            
            app.UIAxes_1.XTickLabel = [];
            app.UIAxes_2.XTickLabel = [];
            app.UIAxes_3.XTickLabel = [];
            
            app.UIAxes_1.YTickLabel = [];
            app.UIAxes_2.YTickLabel = [];
            app.UIAxes_3.YTickLabel = [];
            
            app.UIAxes_1.XColor = [1 1 1];
            app.UIAxes_2.XColor = [1 1 1];
            app.UIAxes_3.XColor = [1 1 1];
            
            app.UIAxes_1.YColor = [1 1 1];
            app.UIAxes_2.YColor = [1 1 1];
            app.UIAxes_3.YColor = [1 1 1];
            
            % Fill the electrode info table


            % Wait until all electrodes labeled
            uiwait
        end

        % Callback function
        function Slider_1ValueChanging(app, event)

        end

        % Value changed function: Slider_1
        function Slider_1ValueChanged(app, event)
            value = app.Slider_1.Value;
            Xcorr = round(value);
           
            Atemp = squeeze(app.ProcessedObj.AnatMat(Xcorr,:,:));
            Btemp = squeeze(app.ProcessedObj.CTmat(Xcorr,:,:));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_1,Ctemp);
            colormap(app.UIAxes_1,'gray')
            axis(app.UIAxes_1,'tight')
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            Xcorr = round(value);
            Atemp = squeeze(app.ProcessedObj.AnatMat(:,Xcorr,:));
            Btemp = squeeze(app.ProcessedObj.CTmat(:,Xcorr,:));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_2,Ctemp);
            colormap(app.UIAxes_2,'gray')
            axis(app.UIAxes_2,'tight')
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            Xcorr = round(value);           
            Atemp = squeeze(app.ProcessedObj.AnatMat(:,:,Xcorr));
            Btemp = squeeze(app.ProcessedObj.CTmat(:,:,Xcorr));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_3,Ctemp);
            colormap(app.UIAxes_3,'gray')
            axis(app.UIAxes_3,'tight')
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            value = app.Slider_4.Value;
            CorrTemp = app.ProcessedObj.Corr(round(value),:);
            
            % Axis 1
            Atemp = squeeze(app.ProcessedObj.AnatMat(round(CorrTemp(1)),:,:));
            Btemp = squeeze(app.ProcessedObj.CTmat(round(CorrTemp(1)),:,:));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_1,Ctemp);
            hold(app.UIAxes_1,'on')
            plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
            colormap(app.UIAxes_1,'gray')
            axis(app.UIAxes_1,'tight')
                        
            % Axis 2
            Atemp = squeeze(app.ProcessedObj.AnatMat(:,round(CorrTemp(2)),:));
            Btemp = squeeze(app.ProcessedObj.CTmat(:,round(CorrTemp(2)),:));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_2,Ctemp);
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
            colormap(app.UIAxes_2,'gray')
            axis(app.UIAxes_2,'tight')
            
            % Axis 3
            Atemp = squeeze(app.ProcessedObj.AnatMat(:,:,round(CorrTemp(3))));
            Btemp = squeeze(app.ProcessedObj.CTmat(:,:,round(CorrTemp(3))));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_3,Ctemp);
            hold(app.UIAxes_3,'on')
            plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
            colormap(app.UIAxes_3,'gray')
            axis(app.UIAxes_3,'tight')
        end

        % Button pushed function: TargetButton
        function TargetButtonPushed(app, event)
            value = app.Slider_4.Value;
            CorrTemp = app.ProcessedObj.Corr(round(value),:);
            app.EditFieldTargetCorr.Value = num2str(CorrTemp,4);
            
        end

        % Callback function: EditFieldEntryCorr, FinishButton
        function EditFieldEntryCorrValueChanged(app, event)

            uiresume
        end

        % Button pushed function: EntryButton
        function EntryButtonPushed(app, event)
            value = app.Slider_4.Value;
            CorrTemp = app.ProcessedObj.Corr(round(value),:);
            app.EditFieldEntryCorr.Value = num2str(CorrTemp,4);
        end

        % Callback function
        function UIselectButtonPushed(app, event)
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DELLOLocAutoUIFigure = uifigure;
            app.DELLOLocAutoUIFigure.Name = 'DELLO Loc Auto';
            app.UIAxes_1 = uiaxes(app.DELLOLocAutoUIFigure);
            title(app.UIAxes_1, 'SliceView1')
            app.UIAxes_2 = uiaxes(app.DELLOLocAutoUIFigure);
            title(app.UIAxes_2, 'SliceView2')
            app.UIAxes_3 = uiaxes(app.DELLOLocAutoUIFigure);
            title(app.UIAxes_3, 'SliceView3')
            app.Slider_1 = uislider(app.DELLOLocAutoUIFigure);
            app.Slider_1.MajorTicks = [];
            app.Slider_1.MajorTickLabels = {};
            app.Slider_1.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_1.MinorTicks = [];
            app.Slider_2 = uislider(app.DELLOLocAutoUIFigure);
            app.Slider_2.MajorTicks = [];
            app.Slider_2.MajorTickLabels = {};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_3 = uislider(app.DELLOLocAutoUIFigure);
            app.Slider_3.MajorTicks = [];
            app.Slider_3.MajorTickLabels = {};
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.MinorTicks = [];
            app.Slider_4 = uislider(app.DELLOLocAutoUIFigure);
            app.Slider_4.MajorTicks = [];
            app.Slider_4.MajorTickLabels = {};
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.MinorTicks = [];
            app.TargetButton = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.TargetButton.ButtonPushedFcn = createCallbackFcn(app, @TargetButtonPushed, true);
            app.TargetButton.Text = 'Target';
            app.EntryButton = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.EntryButton.ButtonPushedFcn = createCallbackFcn(app, @EntryButtonPushed, true);
            app.EntryButton.Text = 'Entry';
            app.EditFieldTargetCorr = uieditfield(app.DELLOLocAutoUIFigure, 'text');
            app.EditFieldTargetCorr.Editable = 'off';
            app.EditField_2Label = uilabel(app.DELLOLocAutoUIFigure);
            app.EditField_2Label.Text = '';
            app.EditFieldEntryCorr = uieditfield(app.DELLOLocAutoUIFigure, 'text');
            app.EditFieldEntryCorr.ValueChangedFcn = createCallbackFcn(app, @EditFieldEntryCorrValueChanged, true);
            app.EditFieldEntryCorr.Editable = 'off';
            app.DepthElectrodesCoordinatesLabel = uilabel(app.DELLOLocAutoUIFigure);
            app.DepthElectrodesCoordinatesLabel.Text = 'Depth Electrodes Coordinates';
            app.FinishButton = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @EditFieldEntryCorrValueChanged, true);
            app.FinishButton.Text = 'Finish';
            app.UITableElecInfo = uitable(app.DELLOLocAutoUIFigure);
            app.UITableElecInfo.ColumnName = {'Names'; 'NChannel'};
            app.UITableElecInfo.RowName = {};
            app.GenerateButton = uibutton(app.DELLOLocAutoUIFigure, 'push');
            app.GenerateButton.Text = 'Generate';
            app.EditField = uieditfield(app.DELLOLocAutoUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.StatusLabel = uilabel(app.DELLOLocAutoUIFigure);
            app.StatusLabel.Text = 'Status';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DELLO_loc_Panel_auto(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DELLOLocAutoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DELLOLocAutoUIFigure)
        end
    end
end